classdef CVT_Calculate_Swash_Control_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PowerSplitCVTDesign            matlab.ui.Figure
        PowerSplitCVTDesignAppLabel    matlab.ui.control.Label
        MaxSwashDifferenceEditField    matlab.ui.control.NumericEditField
        MaxDifferenceEditFieldLabel    matlab.ui.control.Label
        tgtMaxSwashEditField           matlab.ui.control.NumericEditField
        TargetMaxSwashRatioLabel       matlab.ui.control.Label
        tgtVSpdRanges4EndOptEditField  matlab.ui.control.NumericEditField
        tgtVSpdRanges3EndOptEditField  matlab.ui.control.NumericEditField
        tgtVSpdRanges2EndOptEditField  matlab.ui.control.NumericEditField
        tgtVSpdRanges1EndOptEditField  matlab.ui.control.NumericEditField
        AchievedLabel                  matlab.ui.control.Label
        TargetLabel                    matlab.ui.control.Label
        tgtVSpdRanges4EndEditField     matlab.ui.control.NumericEditField
        Range1Label_4                  matlab.ui.control.Label
        tgtVSpdRanges3EndEditField     matlab.ui.control.NumericEditField
        Range1Label_3                  matlab.ui.control.Label
        tgtVSpdRanges2EndEditField     matlab.ui.control.NumericEditField
        Range1Label_2                  matlab.ui.control.Label
        tgtVSpdRanges1EndEditField     matlab.ui.control.NumericEditField
        Range1Label                    matlab.ui.control.Label
        nTeethSunP4OptEditField        matlab.ui.control.NumericEditField
        nTeethSunP3OptEditField        matlab.ui.control.NumericEditField
        nTeethSunP2OptEditField        matlab.ui.control.NumericEditField
        nTeethSunP1OptEditField        matlab.ui.control.NumericEditField
        OptimizeButton                 matlab.ui.control.Button
        nGearHSOutOptEditField         matlab.ui.control.NumericEditField
        PlotDesignLabel                matlab.ui.control.Label
        InitialButton                  matlab.ui.control.Button
        nTeethSunP4EditField           matlab.ui.control.NumericEditField
        TeethSunGearPlanetary4EditFieldLabel  matlab.ui.control.Label
        nTeethSunP3EditField           matlab.ui.control.NumericEditField
        TeethSunGearPlanetary3EditFieldLabel  matlab.ui.control.Label
        nTeethSunP2EditField           matlab.ui.control.NumericEditField
        TeethSunGearPlanetary2EditFieldLabel  matlab.ui.control.Label
        nTeethSunP1EditField           matlab.ui.control.NumericEditField
        TeethSunGearPlanetary1EditFieldLabel  matlab.ui.control.Label
        nGearHSOutEditField            matlab.ui.control.NumericEditField
        HydrostaticOutputGearRatioEditFieldLabel  matlab.ui.control.Label
        rWhlEditField                  matlab.ui.control.NumericEditField
        WheelRadiusmEditFieldLabel     matlab.ui.control.Label
        nFinalDriveEditField           matlab.ui.control.NumericEditField
        WheelHubPlanetaryGearRatioEditFieldLabel  matlab.ui.control.Label
        nDiffEditField                 matlab.ui.control.NumericEditField
        DifferentialGearRatioEditFieldLabel  matlab.ui.control.Label
        nTRingEditField                matlab.ui.control.NumericEditField
        ofTeethPlanetaryGearallEditFieldLabel  matlab.ui.control.Label
        nGearCVTOutEditField           matlab.ui.control.NumericEditField
        CVTOutputTransferGearRatioLabel  matlab.ui.control.Label
        pumpVolEffEditField            matlab.ui.control.NumericEditField
        PumpVolumetricEfficiency01EditFieldLabel  matlab.ui.control.Label
        nHSPumpMotorEditField          matlab.ui.control.NumericEditField
        RatioNominalPumpDisplacementEditFieldLabel  matlab.ui.control.Label
        wPumpNomEditField              matlab.ui.control.NumericEditField
        NominalPumpSpeedrpmEditFieldLabel  matlab.ui.control.Label
        wEngEditField                  matlab.ui.control.NumericEditField
        NominalEngineSpeedrpmEditFieldLabel  matlab.ui.control.Label
        SwashControlResults            matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.SwashControlResults.XAxisLocation = 'origin';
            app.SwashControlResults.YAxisLocation = 'origin';
            app.SwashControlResults.XLim = [-1.1 1.1];
            app.SwashControlResults.TitleFontWeight = "bold";
            app.SwashControlResults.Title.FontSize = 16;
            app.SwashControlResults.XLabel.FontWeight = "bold";
            app.SwashControlResults.YLabel.FontWeight = "bold";

        end

        % Button pushed function: InitialButton
        function InitialButtonPushed(app, event)
            wEng         = app.wEngEditField.Value;
            wPumpNom     = app.wPumpNomEditField.Value;
            nHSPumpMotor = app.nHSPumpMotorEditField.Value;
            pumpVolEff   = app.pumpVolEffEditField.Value;
            nGearCVTOut  = app.nGearCVTOutEditField.Value;
            nTRing       = app.nTRingEditField.Value;
            nDiff        = app.nDiffEditField.Value;
            nFinalDrive  = app.nFinalDriveEditField.Value;
            rWhl         = app.rWhlEditField.Value;
            nGearHSOut   = app.nGearHSOutEditField.Value;
            nTSuns       = [0 0 0 0];
            nTSuns(1)    = app.nTeethSunP1EditField.Value;
            nTSuns(2)    = app.nTeethSunP2EditField.Value;
            nTSuns(3)    = app.nTeethSunP3EditField.Value;
            nTSuns(4)    = app.nTeethSunP4EditField.Value;
            tgtMaxSwash  = app.tgtMaxSwashEditField.Value;
            tgtVSpdRanges       = [0 0 0 0 0];
            tgtVSpdRanges(2)    = app.tgtVSpdRanges1EndEditField.Value;
            tgtVSpdRanges(3)    = app.tgtVSpdRanges2EndEditField.Value;
            tgtVSpdRanges(4)    = app.tgtVSpdRanges3EndEditField.Value;
            tgtVSpdRanges(5)    = app.tgtVSpdRanges4EndEditField.Value;

            % Derived parameter
            vWhl2CVTOut = 1/(3.6*rWhl*pi/30)*nGearCVTOut*nDiff*nFinalDrive;


            [swashCtrlIdeal, ~, ~] = cvtCalcSwashControl(...
                tgtVSpdRanges,rWhl,nGearCVTOut,nDiff,nFinalDrive,wEng,wPumpNom,...
                nHSPumpMotor,tgtMaxSwash,nTRing,nTSuns,nGearHSOut,pumpVolEff,'plot',app.SwashControlResults);

            tgtVSpdRangesEnddOpt = swashCtrlIdeal(:,2)*wEng/vWhl2CVTOut;
            app.tgtVSpdRanges1EndOptEditField.Value = tgtVSpdRangesEnddOpt(2);
            app.tgtVSpdRanges2EndOptEditField.Value = tgtVSpdRangesEnddOpt(3);
            app.tgtVSpdRanges3EndOptEditField.Value = tgtVSpdRangesEnddOpt(4);
            app.tgtVSpdRanges4EndOptEditField.Value = tgtVSpdRangesEnddOpt(5);

            clrBad   = '#FBE5D6';
            clrGood  = '#CCEFFC';
            SpdThreshOK = 0.5;
            maxSwDiffOK = 0.05;

            if(abs(tgtVSpdRangesEnddOpt(2)-tgtVSpdRanges(2))>SpdThreshOK)
                app.tgtVSpdRanges1EndOptEditField.BackgroundColor = clrBad;
            else
                app.tgtVSpdRanges1EndOptEditField.BackgroundColor = clrGood;
            end
            if(abs(tgtVSpdRangesEnddOpt(3)-tgtVSpdRanges(3))>SpdThreshOK)
                app.tgtVSpdRanges2EndOptEditField.BackgroundColor = clrBad;
            else
                app.tgtVSpdRanges2EndOptEditField.BackgroundColor = clrGood;
            end
            if(abs(tgtVSpdRangesEnddOpt(4)-tgtVSpdRanges(4))>SpdThreshOK)
                app.tgtVSpdRanges3EndOptEditField.BackgroundColor = clrBad;
            else
                app.tgtVSpdRanges3EndOptEditField.BackgroundColor = clrGood;
            end
            if(abs(tgtVSpdRangesEnddOpt(5)-tgtVSpdRanges(5))>SpdThreshOK)
                app.tgtVSpdRanges4EndOptEditField.BackgroundColor = clrBad;
            else
                app.tgtVSpdRanges4EndOptEditField.BackgroundColor = clrGood;
            end
            
            max_diff_tgt_swash = max(abs(tgtMaxSwash-abs(swashCtrlIdeal(:,1))'));
            app.MaxSwashDifferenceEditField.Value = max_diff_tgt_swash;
            if(max_diff_tgt_swash>maxSwDiffOK)
                app.MaxSwashDifferenceEditField.BackgroundColor = clrBad;
            else
                app.MaxSwashDifferenceEditField.BackgroundColor = clrGood;
            end

        end

        % Button pushed function: OptimizeButton
        function OptimizeButtonPushed(app, event)
            wEng         = app.wEngEditField.Value;
            wPumpNom     = app.wPumpNomEditField.Value;
            nHSPumpMotor = app.nHSPumpMotorEditField.Value;
            pumpVolEff   = app.pumpVolEffEditField.Value;
            nGearCVTOut  = app.nGearCVTOutEditField.Value;
            nTRing       = app.nTRingEditField.Value;
            nDiff        = app.nDiffEditField.Value;
            nFinalDrive  = app.nFinalDriveEditField.Value;
            rWhl         = app.rWhlEditField.Value;
            nGearHSOut   = app.nGearHSOutEditField.Value;
            nTSuns       = [0 0 0 0];
            nTSuns(1)    = app.nTeethSunP1EditField.Value;
            nTSuns(2)    = app.nTeethSunP2EditField.Value;
            nTSuns(3)    = app.nTeethSunP3EditField.Value;
            nTSuns(4)    = app.nTeethSunP4EditField.Value;
            tgtMaxSwash  = app.tgtMaxSwashEditField.Value;
            tgtVSpdRanges       = [0 0 0 0 0];
            tgtVSpdRanges(2)    = app.tgtVSpdRanges1EndEditField.Value;
            tgtVSpdRanges(3)    = app.tgtVSpdRanges2EndEditField.Value;
            tgtVSpdRanges(4)    = app.tgtVSpdRanges3EndEditField.Value;
            tgtVSpdRanges(5)    = app.tgtVSpdRanges4EndEditField.Value;

            % Derived parameter
            vWhl2CVTOut = 1/(3.6*rWhl*pi/30)*nGearCVTOut*nDiff*nFinalDrive;

            % Initial values
            xInit = [nGearHSOut nTSuns];

            % Define limits for parameter values
            lb = [0.1,4,4,4,4];   % Lower bound
            ub = [5,28,28,28,28]; % Upper bound

            % Define which parameters are integers (number of sun gear teeth)
            intcon = [2,3,4,5];

            % Assemble objective function handle
            objFcnHdl = @(x)cvtObjFunGearRatios4SpdsSwash(x, tgtVSpdRanges, ...
                nHSPumpMotor,wEng,wPumpNom, vWhl2CVTOut, nTRing,tgtMaxSwash);

            % Call Genetic Algorithm
            [xOpt,~,~,~] = ga(objFcnHdl,size(lb,2),[],[],[],[],lb,ub,[],intcon);
            xFinal = [xOpt(1) 3*xOpt(2:5)];

            [swashCtrlIdeal, ~, aw_sw] = cvtCalcSwashControl(...
                tgtVSpdRanges,rWhl,nGearCVTOut,nDiff,nFinalDrive,wEng,wPumpNom,...
                nHSPumpMotor,tgtMaxSwash,nTRing,xFinal(2:5),xFinal(1),pumpVolEff,'plot',app.SwashControlResults);
            
            tgtVSpdRangesEnddOpt = swashCtrlIdeal(:,2)*wEng/vWhl2CVTOut;
            app.tgtVSpdRanges1EndOptEditField.Value = tgtVSpdRangesEnddOpt(2);
            app.tgtVSpdRanges2EndOptEditField.Value = tgtVSpdRangesEnddOpt(3);
            app.tgtVSpdRanges3EndOptEditField.Value = tgtVSpdRangesEnddOpt(4);
            app.tgtVSpdRanges4EndOptEditField.Value = tgtVSpdRangesEnddOpt(5);

            app.nGearHSOutOptEditField.Value  = xFinal(1);
            app.nTeethSunP1OptEditField.Value = xFinal(2);
            app.nTeethSunP2OptEditField.Value = xFinal(3);
            app.nTeethSunP3OptEditField.Value = xFinal(4);
            app.nTeethSunP4OptEditField.Value = xFinal(5);

            clrBad   = '#FBE5D6';
            clrGood  = '#CCEFFC';
            threshOK = 0.5;
            maxSwDiffOK = 0.05;

            if(abs(tgtVSpdRangesEnddOpt(2)-tgtVSpdRanges(2))>threshOK)
                app.tgtVSpdRanges1EndOptEditField.BackgroundColor = clrBad;
            else
                app.tgtVSpdRanges1EndOptEditField.BackgroundColor = clrGood;
            end
            if(abs(tgtVSpdRangesEnddOpt(3)-tgtVSpdRanges(3))>threshOK)
                app.tgtVSpdRanges2EndOptEditField.BackgroundColor = clrBad;
            else
                app.tgtVSpdRanges2EndOptEditField.BackgroundColor = clrGood;
            end
            if(abs(tgtVSpdRangesEnddOpt(4)-tgtVSpdRanges(4))>threshOK)
                app.tgtVSpdRanges3EndOptEditField.BackgroundColor = clrBad;
            else
                app.tgtVSpdRanges3EndOptEditField.BackgroundColor = clrGood;
            end
            if(abs(tgtVSpdRangesEnddOpt(5)-tgtVSpdRanges(5))>threshOK)
                app.tgtVSpdRanges4EndOptEditField.BackgroundColor = clrBad;
            else
                app.tgtVSpdRanges4EndOptEditField.BackgroundColor = clrGood;
            end

            max_diff_tgt_swash = max(abs(tgtMaxSwash-abs(swashCtrlIdeal(:,1))'));
            app.MaxSwashDifferenceEditField.Value = max_diff_tgt_swash;
            if(max_diff_tgt_swash>maxSwDiffOK)
                app.MaxSwashDifferenceEditField.BackgroundColor = clrBad;
            else
                app.MaxSwashDifferenceEditField.BackgroundColor = clrGood;
            end            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PowerSplitCVTDesign = uifigure('Visible', 'off');
            app.PowerSplitCVTDesign.Name = 'Power Split CVT Design';
            app.SwashControlResults = uiaxes(app.PowerSplitCVTDesign);
            title(app.SwashControlResults, 'Swash Plate Ratio by Speed Range')
            xlabel(app.SwashControlResults, 'Displacement Ratio Hydrostatic Pump (0-1)')
            ylabel(app.SwashControlResults, 'Vehicle Speed (km/h)')
            app.NominalEngineSpeedrpmEditFieldLabel = uilabel(app.PowerSplitCVTDesign);
            app.NominalEngineSpeedrpmEditFieldLabel.Text = 'Nominal Engine Speed (rpm)';
            app.wEngEditField = uieditfield(app.PowerSplitCVTDesign, 'numeric');
            app.wEngEditField.Value = 2200;
            app.NominalPumpSpeedrpmEditFieldLabel = uilabel(app.PowerSplitCVTDesign);
            app.NominalPumpSpeedrpmEditFieldLabel.Text = 'Nominal Pump Speed (rpm)';
            app.wPumpNomEditField = uieditfield(app.PowerSplitCVTDesign, 'numeric');
            app.wPumpNomEditField.Value = 3350;
            app.RatioNominalPumpDisplacementEditFieldLabel = uilabel(app.PowerSplitCVTDesign);
            app.RatioNominalPumpDisplacementEditFieldLabel.Text = 'Ratio (Pump Disp./(Motor Disp.)';
            app.nHSPumpMotorEditField = uieditfield(app.PowerSplitCVTDesign, 'numeric');
            app.nHSPumpMotorEditField.Value = 1.2941;
            app.PumpVolumetricEfficiency01EditFieldLabel = uilabel(app.PowerSplitCVTDesign);
            app.PumpVolumetricEfficiency01EditFieldLabel.Text = 'Pump Volumetric Efficiency (0-1)';
            app.pumpVolEffEditField = uieditfield(app.PowerSplitCVTDesign, 'numeric');
            app.pumpVolEffEditField.Value = 0.97;
            app.CVTOutputTransferGearRatioLabel = uilabel(app.PowerSplitCVTDesign);
            app.CVTOutputTransferGearRatioLabel.Text = 'CVT Output Transfer Gear Ratio';
            app.nGearCVTOutEditField = uieditfield(app.PowerSplitCVTDesign, 'numeric');
            app.nGearCVTOutEditField.Value = 1.7647;
            app.ofTeethPlanetaryGearallEditFieldLabel = uilabel(app.PowerSplitCVTDesign);
            app.ofTeethPlanetaryGearallEditFieldLabel.Text = '# of Teeth Planetary Gear (all)';
            app.nTRingEditField = uieditfield(app.PowerSplitCVTDesign, 'numeric');
            app.nTRingEditField.Value = 99;
            app.DifferentialGearRatioEditFieldLabel = uilabel(app.PowerSplitCVTDesign);
            app.DifferentialGearRatioEditFieldLabel.Text = 'Differential Gear Ratio';
            app.nDiffEditField = uieditfield(app.PowerSplitCVTDesign, 'numeric');
            app.nDiffEditField.Value = 2.8;
            app.WheelHubPlanetaryGearRatioEditFieldLabel = uilabel(app.PowerSplitCVTDesign);
            app.WheelHubPlanetaryGearRatioEditFieldLabel.Text = 'Wheel Hub Planetary Gear Ratio';
            app.nFinalDriveEditField = uieditfield(app.PowerSplitCVTDesign, 'numeric');
            app.nFinalDriveEditField.Value = 4.625;
            app.WheelRadiusmEditFieldLabel = uilabel(app.PowerSplitCVTDesign);
            app.WheelRadiusmEditFieldLabel.Text = 'Wheel Radius (m)';
            app.rWhlEditField = uieditfield(app.PowerSplitCVTDesign, 'numeric');
            app.rWhlEditField.Value = 0.3937;
            app.HydrostaticOutputGearRatioEditFieldLabel = uilabel(app.PowerSplitCVTDesign);
            app.HydrostaticOutputGearRatioEditFieldLabel.Text = 'Hydrostatic Output Gear Ratio';
            app.nGearHSOutEditField = uieditfield(app.PowerSplitCVTDesign, 'numeric');
            app.nGearHSOutEditField.Value = 1.425;
            app.TeethSunGearPlanetary1EditFieldLabel = uilabel(app.PowerSplitCVTDesign);
            app.TeethSunGearPlanetary1EditFieldLabel.Text = '# Teeth Sun Gear, Planetary 1';
            app.nTeethSunP1EditField = uieditfield(app.PowerSplitCVTDesign, 'numeric');
            app.nTeethSunP1EditField.Value = 39;
            app.TeethSunGearPlanetary2EditFieldLabel = uilabel(app.PowerSplitCVTDesign);
            app.TeethSunGearPlanetary2EditFieldLabel.Text = '# Teeth Sun Gear, Planetary 2';
            app.nTeethSunP2EditField = uieditfield(app.PowerSplitCVTDesign, 'numeric');
            app.nTeethSunP2EditField.Value = 49;
            app.TeethSunGearPlanetary3EditFieldLabel = uilabel(app.PowerSplitCVTDesign);
            app.TeethSunGearPlanetary3EditFieldLabel.Text = '# Teeth Sun Gear, Planetary 3';
            app.nTeethSunP3EditField = uieditfield(app.PowerSplitCVTDesign, 'numeric');
            app.nTeethSunP3EditField.Value = 50;
            app.TeethSunGearPlanetary4EditFieldLabel = uilabel(app.PowerSplitCVTDesign);
            app.TeethSunGearPlanetary4EditFieldLabel.Text = '# Teeth Sun Gear, Planetary 4';
            app.nTeethSunP4EditField = uieditfield(app.PowerSplitCVTDesign, 'numeric');
            app.nTeethSunP4EditField.Value = 50;
            app.InitialButton = uibutton(app.PowerSplitCVTDesign, 'push');
            app.InitialButton.ButtonPushedFcn = createCallbackFcn(app, @InitialButtonPushed, true);
            app.InitialButton.Text = 'Initial';
            app.PlotDesignLabel = uilabel(app.PowerSplitCVTDesign);
            app.PlotDesignLabel.Text = 'Plot Design';
            app.nGearHSOutOptEditField = uieditfield(app.PowerSplitCVTDesign, 'numeric');
            app.nGearHSOutOptEditField.Editable = 'off';
            app.OptimizeButton = uibutton(app.PowerSplitCVTDesign, 'push');
            app.OptimizeButton.ButtonPushedFcn = createCallbackFcn(app, @OptimizeButtonPushed, true);
            app.OptimizeButton.Text = 'Optimize';
            app.nTeethSunP1OptEditField = uieditfield(app.PowerSplitCVTDesign, 'numeric');
            app.nTeethSunP1OptEditField.Editable = 'off';
            app.nTeethSunP2OptEditField = uieditfield(app.PowerSplitCVTDesign, 'numeric');
            app.nTeethSunP2OptEditField.Editable = 'off';
            app.nTeethSunP3OptEditField = uieditfield(app.PowerSplitCVTDesign, 'numeric');
            app.nTeethSunP3OptEditField.Editable = 'off';
            app.nTeethSunP4OptEditField = uieditfield(app.PowerSplitCVTDesign, 'numeric');
            app.nTeethSunP4OptEditField.Editable = 'off';
            app.Range1Label = uilabel(app.PowerSplitCVTDesign);
            app.Range1Label.Text = 'Range 1';
            app.tgtVSpdRanges1EndEditField = uieditfield(app.PowerSplitCVTDesign, 'numeric');
            app.tgtVSpdRanges1EndEditField.ValueDisplayFormat = '%3.2f';
            app.tgtVSpdRanges1EndEditField.Value = 3;
            app.Range1Label_2 = uilabel(app.PowerSplitCVTDesign);
            app.Range1Label_2.Text = 'Range 2';
            app.tgtVSpdRanges2EndEditField = uieditfield(app.PowerSplitCVTDesign, 'numeric');
            app.tgtVSpdRanges2EndEditField.ValueDisplayFormat = '%3.2f';
            app.tgtVSpdRanges2EndEditField.Value = 6.4;
            app.Range1Label_3 = uilabel(app.PowerSplitCVTDesign);
            app.Range1Label_3.Text = 'Range 3';
            app.tgtVSpdRanges3EndEditField = uieditfield(app.PowerSplitCVTDesign, 'numeric');
            app.tgtVSpdRanges3EndEditField.ValueDisplayFormat = '%3.2f';
            app.tgtVSpdRanges3EndEditField.Value = 14.2;
            app.Range1Label_4 = uilabel(app.PowerSplitCVTDesign);
            app.Range1Label_4.Text = 'Range 4';
            app.tgtVSpdRanges4EndEditField = uieditfield(app.PowerSplitCVTDesign, 'numeric');
            app.tgtVSpdRanges4EndEditField.ValueDisplayFormat = '%3.2f';
            app.tgtVSpdRanges4EndEditField.Value = 30;
            app.TargetLabel = uilabel(app.PowerSplitCVTDesign);
            app.TargetLabel.Text = 'Target';
            app.AchievedLabel = uilabel(app.PowerSplitCVTDesign);
            app.AchievedLabel.Text = 'Achieved';
            app.tgtVSpdRanges1EndOptEditField = uieditfield(app.PowerSplitCVTDesign, 'numeric');
            app.tgtVSpdRanges1EndOptEditField.ValueDisplayFormat = '%3.2f';
            app.tgtVSpdRanges1EndOptEditField.Editable = 'off';
            app.tgtVSpdRanges2EndOptEditField = uieditfield(app.PowerSplitCVTDesign, 'numeric');
            app.tgtVSpdRanges2EndOptEditField.ValueDisplayFormat = '%3.2f';
            app.tgtVSpdRanges2EndOptEditField.Editable = 'off';
            app.tgtVSpdRanges3EndOptEditField = uieditfield(app.PowerSplitCVTDesign, 'numeric');
            app.tgtVSpdRanges3EndOptEditField.ValueDisplayFormat = '%3.2f';
            app.tgtVSpdRanges3EndOptEditField.Editable = 'off';
            app.tgtVSpdRanges4EndOptEditField = uieditfield(app.PowerSplitCVTDesign, 'numeric');
            app.tgtVSpdRanges4EndOptEditField.ValueDisplayFormat = '%3.2f';
            app.tgtVSpdRanges4EndOptEditField.Editable = 'off';
            app.TargetMaxSwashRatioLabel = uilabel(app.PowerSplitCVTDesign);
            app.TargetMaxSwashRatioLabel.Text = 'Target  Max Swash Ratio';
            app.tgtMaxSwashEditField = uieditfield(app.PowerSplitCVTDesign, 'numeric');
            app.tgtMaxSwashEditField.ValueDisplayFormat = '%1.4f';
            app.tgtMaxSwashEditField.Value = 0.7317;
            app.MaxDifferenceEditFieldLabel = uilabel(app.PowerSplitCVTDesign);
            app.MaxDifferenceEditFieldLabel.Text = 'Max Difference';
            app.MaxSwashDifferenceEditField = uieditfield(app.PowerSplitCVTDesign, 'numeric');
            app.MaxSwashDifferenceEditField.ValueDisplayFormat = '%1.4f';
            app.MaxSwashDifferenceEditField.Editable = 'off';
            app.PowerSplitCVTDesignAppLabel = uilabel(app.PowerSplitCVTDesign);
            app.PowerSplitCVTDesignAppLabel.Text = 'Power Split CVT Design App';
            % Show the figure after all components are created
            app.PowerSplitCVTDesign.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CVT_Calculate_Swash_Control_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PowerSplitCVTDesign)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PowerSplitCVTDesign)
        end
    end
end